classdef Sign_in_Create_account_Reset_password < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ResetpasswordLabel             matlab.ui.control.Label
        OTPsentLabel                   matlab.ui.control.Label
        SendOTPButton                  matlab.ui.control.Button
        EmailaddressEditField          matlab.ui.control.EditField
        EmailaddressEditFieldLabel     matlab.ui.control.Label
        AccountcreatedsuccessfullyLabel  matlab.ui.control.Label
        Image_2                        matlab.ui.control.Image
        PasswordsdonotmatchLabel       matlab.ui.control.Label
        ThisusernameisusedbysomeoneelseLabel  matlab.ui.control.Label
        ResetyourpasswordButton        matlab.ui.control.Button
        CreateanaccountButton          matlab.ui.control.Button
        SigninButton_2                 matlab.ui.control.Button
        HaveanaccountLabel             matlab.ui.control.Label
        CreateaccountButton            matlab.ui.control.Button
        ConfirmpasswordEditField       matlab.ui.control.EditField
        ConfirmpasswordEditFieldLabel  matlab.ui.control.Label
        PasswordEditField_2            matlab.ui.control.EditField
        PasswordEditField_2Label       matlab.ui.control.Label
        UsernameEditField_2            matlab.ui.control.EditField
        UsernameEditField_2Label       matlab.ui.control.Label
        SignupforanaccountLabel        matlab.ui.control.Label
        LogoutButton                   matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        LoginsuccessfullyLabel         matlab.ui.control.Label
        IncorrectpasswordLabel         matlab.ui.control.Label
        UsernamedoesnotexistLabel      matlab.ui.control.Label
        DonthaveaaccountLabel          matlab.ui.control.Label
        ForgetyourpasswordLabel        matlab.ui.control.Label
        SigninButton                   matlab.ui.control.Button
        SigninLabel                    matlab.ui.control.Label
        PasswordEditField              matlab.ui.control.EditField
        PasswordEditFieldLabel         matlab.ui.control.Label
        UsernameEditField              matlab.ui.control.EditField
        UsernameEditFieldLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SigninButton
        function SigninButtonPushed(app, event)
            app.UsernamedoesnotexistLabel.Visible = 'off';
            app.IncorrectpasswordLabel.Visible = 'off';
            
            
            PW=convertCharsToStrings(app.PasswordEditField.Value);
            UN=convertCharsToStrings(app.UsernameEditField.Value);
            file=fopen("C:/Users/ACER_USER/Desktop/Matlab/Summer Research Intern 2021/GUI/Database.txt","r");
            database=fscanf(file,"%s");
            s1=split(database,"@");  %split 1
            s2=split(s1(2:length(s1)),"#");  %split 2
            
            siz=size(s2);
            for i=1:siz(1)
                for j=1:2
                    tem=cell2mat(s2(i,j));   %convert to normal vector
                    mat(i,j)=convertCharsToStrings(tem);
                end
            end
            
            uns=mat(:,1);
            pws=mat(:,2);
            
            
            i=1;
            I=0;
            while i<=length(uns)
                if uns(i)==UN
                    I=i;
                end
                i=i+1;
            end


            if UN~=""
                if I==0
                    app.UsernamedoesnotexistLabel.Visible = 'on';
                else
                    if pws(I)==PW
                        app.SigninLabel.Visible = 'off';
                        app.SigninButton.Visible = 'off';
                        app.PasswordEditField.Visible = 'off';
                        app.PasswordEditFieldLabel.Visible = 'off';
                        app.UsernameEditField.Visible = 'off';
                        app.UsernameEditFieldLabel.Visible = 'off';
                        app.ForgetyourpasswordLabel.Visible = 'off';
                        app.DonthaveaaccountLabel.Visible = 'off';
                        app.CreateanaccountButton.Visible = 'off';
                        app.ResetyourpasswordButton.Visible = 'off';
                    
                        app.Image.Visible = 'on';
                        app.LoginsuccessfullyLabel.Visible = 'on';
                        app.LogoutButton.Visible = 'on';
                    
                    else
                        app.IncorrectpasswordLabel.Visible = 'on';
                    end
                end
            end
            
            fclose(file);


        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
            app.SigninLabel.Visible = 'on';
            app.SigninButton.Visible = 'on';
            app.PasswordEditField.Visible = 'on';
            app.PasswordEditFieldLabel.Visible = 'on';
            app.UsernameEditField.Visible = 'on';
            app.UsernameEditFieldLabel.Visible = 'on';
            app.ForgetyourpasswordLabel.Visible = 'on';
            app.DonthaveaaccountLabel.Visible = 'on';
            app.CreateanaccountButton.Visible = 'on';
            app.ResetyourpasswordButton.Visible = 'on';
            
            app.Image.Visible = 'off';
            app.LoginsuccessfullyLabel.Visible = 'off';
            app.LogoutButton.Visible = 'off';
            
            app.UsernameEditField.Value = '';
            app.PasswordEditField.Value = '';
        end

        % Button pushed function: SigninButton_2
        function SigninButton_2Pushed(app, event)
            app.SigninLabel.Visible = 'on';
            app.SigninButton.Visible = 'on';
            app.PasswordEditField.Visible = 'on';
            app.PasswordEditFieldLabel.Visible = 'on';
            app.UsernameEditField.Visible = 'on';
            app.UsernameEditFieldLabel.Visible = 'on';
            app.ForgetyourpasswordLabel.Visible = 'on';
            app.DonthaveaaccountLabel.Visible = 'on';
            app.CreateanaccountButton.Visible = 'on';
            app.ResetyourpasswordButton.Visible = 'on';
            
            app.SignupforanaccountLabel.Visible = 'off';
            app.UsernameEditField_2.Visible = 'off';
            app.UsernameEditField_2Label.Visible = 'off';
            app.PasswordEditField_2.Visible = 'off';
            app.PasswordEditField_2Label.Visible = 'off';
            app.ConfirmpasswordEditField.Visible = 'off';
            app.ConfirmpasswordEditFieldLabel.Visible = 'off';
            app.CreateaccountButton.Visible = 'off';
            app.HaveanaccountLabel.Visible = 'off';
            app.SigninButton_2.Visible = 'off';
            app.ThisusernameisusedbysomeoneelseLabel.Visible = 'off';
            app.PasswordsdonotmatchLabel.Visible = 'off';
            
            % account created successfully page
            app.Image_2.Visible = 'off';
            app.AccountcreatedsuccessfullyLabel.Visible = 'off';
            
            % reset password page
            app.ResetpasswordLabel.Visible = 'off';
            app.EmailaddressEditField.Visible = 'off';
            app.EmailaddressEditFieldLabel.Visible = 'off';
            app.OTPsentLabel.Visible = 'off';
            app.SendOTPButton.Visible = 'off';
            
            app.UsernameEditField.Value = '';
            app.PasswordEditField.Value = '';
        end

        % Button pushed function: CreateanaccountButton
        function CreateanaccountButtonPushed(app, event)
            app.SigninLabel.Visible = 'off';
            app.SigninButton.Visible = 'off';
            app.PasswordEditField.Visible = 'off';
            app.PasswordEditFieldLabel.Visible = 'off';
            app.UsernameEditField.Visible = 'off';
            app.UsernameEditFieldLabel.Visible = 'off';
            app.ForgetyourpasswordLabel.Visible = 'off';
            app.DonthaveaaccountLabel.Visible = 'off';
            app.CreateanaccountButton.Visible = 'off';
            app.ResetyourpasswordButton.Visible = 'off';
            app.UsernamedoesnotexistLabel.Visible = 'off';
            app.IncorrectpasswordLabel.Visible = 'off';
            
            app.SignupforanaccountLabel.Visible = 'on';
            app.UsernameEditField_2.Visible = 'on';
            app.UsernameEditField_2Label.Visible = 'on';
            app.PasswordEditField_2.Visible = 'on';
            app.PasswordEditField_2Label.Visible = 'on';
            app.ConfirmpasswordEditField.Visible = 'on';
            app.ConfirmpasswordEditFieldLabel.Visible = 'on';
            app.CreateaccountButton.Visible = 'on';
            app.HaveanaccountLabel.Visible = 'on';
            app.SigninButton_2.Visible = 'on';
            
            app.SigninButton_2.Position = [335 112 100 22];
            app.UsernameEditField_2.Value = '';
            app.PasswordEditField_2.Value = '';
            app.ConfirmpasswordEditField.Value = '';
        end

        % Button pushed function: CreateaccountButton
        function CreateaccountButtonPushed(app, event)
            app.ThisusernameisusedbysomeoneelseLabel.Visible = 'off';
            app.PasswordsdonotmatchLabel.Visible = 'off';
            
            us=convertCharsToStrings(app.UsernameEditField_2.Value);
            pw=convertCharsToStrings(app.PasswordEditField_2.Value);
            pwc=convertCharsToStrings(app.ConfirmpasswordEditField.Value);
            
            if pw~=pwc
                app.PasswordsdonotmatchLabel.Visible = 'on';
            end
            
            filer=fopen("C:/Users/ACER_USER/Desktop/Matlab/Summer Research Intern 2021/GUI/Database.txt","r");
            
            database=fscanf(filer,"%s");
            s1=split(database,"@");  
            s2=split(s1(2:length(s1)),"#");  
            
            siz=size(s2);
            for i=1:siz(1)
                for j=1:2
                    tem=cell2mat(s2(i,j));   %convert to normal vector
                    mat(i,j)=convertCharsToStrings(tem);
                end
            end
            
            U=mat(:,1);


            I=0;
            for i=1:length(U)   % check if the username is used by someone else or not
                if U(i)==us
                    I=1;
                end
            end
            
            if I==1
                app.ThisusernameisusedbysomeoneelseLabel.Visible = 'on';
                
            else
                if pw==pwc && us~="" && pw~="" && pwc~=""
                    
                    filea=fopen("C:/Users/ACER_USER/Desktop/Matlab/Summer Research Intern 2021/GUI/Database.txt","a");
                    
                    fprintf(filea,"@%s #%s\n",us,pw);
                    
                    fclose(filea);
                    
                    app.SignupforanaccountLabel.Visible = 'off';
                    app.UsernameEditField_2.Visible = 'off';
                    app.UsernameEditField_2Label.Visible = 'off';
                    app.PasswordEditField_2.Visible = 'off';
                    app.PasswordEditField_2Label.Visible = 'off';
                    app.ConfirmpasswordEditField.Visible = 'off';
                    app.ConfirmpasswordEditFieldLabel.Visible = 'off';
                    app.CreateaccountButton.Visible = 'off';
                    app.HaveanaccountLabel.Visible = 'off';
                    
                    app.Image_2.Visible = 'on';
                    app.AccountcreatedsuccessfullyLabel.Visible = 'on';
                    
                    app.SigninButton_2.Position = [281 157 100 22];
                    
                end
            end


        end

        % Button pushed function: ResetyourpasswordButton
        function ResetyourpasswordButtonPushed(app, event)
            app.SigninLabel.Visible = 'off';
            app.SigninButton.Visible = 'off';
            app.UsernameEditField.Visible = 'off';
            app.UsernameEditFieldLabel.Visible = 'off';
            app.PasswordEditField.Visible = 'off';
            app.PasswordEditFieldLabel.Visible = 'off';
            app.ForgetyourpasswordLabel.Visible = 'off';
            app.ResetyourpasswordButton.Visible = 'off';
            app.DonthaveaaccountLabel.Visible = 'off';
            app.CreateanaccountButton.Visible = 'off';
            app.UsernamedoesnotexistLabel.Visible = 'off';
            app.IncorrectpasswordLabel.Visible = 'off';
            
            app.EmailaddressEditField.Visible = 'on';
            app.EmailaddressEditFieldLabel.Visible = 'on';
            app.SendOTPButton.Visible = 'on';
            app.ResetpasswordLabel.Visible = 'on';
            
            app.EmailaddressEditField.Value = '';
            app.SendOTPButton.Text = "Send OTP";
        end

        % Button pushed function: SendOTPButton
        function SendOTPButtonPushed(app, event)
            app.OTPsentLabel.Text = "OTP sent";
            app.OTPsentLabel.FontColor = [0.502 0.502 0.502];
            
            email=convertCharsToStrings(app.EmailaddressEditField.Value);
            
            if email==""
                app.OTPsentLabel.Text = "Please enter your email address";
                app.OTPsentLabel.FontColor = [1 0 0];
                app.OTPsentLabel.Visible = 'on';
                app.SendOTPButton.Text = "Send OTP";
            else
                app.OTPsentLabel.Visible = 'on';
                app.SendOTPButton.Text = "Send again";
                app.SigninButton_2.Position = [281 157 100 22];
                app.SigninButton_2.Visible = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UsernameEditFieldLabel = uilabel(app.UIFigure);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.UIFigure, 'text');
            app.PasswordEditFieldLabel = uilabel(app.UIFigure);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.UIFigure, 'text');
            app.SigninLabel = uilabel(app.UIFigure);
            app.SigninLabel.Text = 'Sign in';
            app.SigninButton = uibutton(app.UIFigure, 'push');
            app.SigninButton.ButtonPushedFcn = createCallbackFcn(app, @SigninButtonPushed, true);
            app.SigninButton.Text = {'Sign in'; ''};
            app.ForgetyourpasswordLabel = uilabel(app.UIFigure);
            app.ForgetyourpasswordLabel.Text = 'Forget your password?';
            app.DonthaveaaccountLabel = uilabel(app.UIFigure);
            app.DonthaveaaccountLabel.Text = 'Don''t have a account?';
            app.UsernamedoesnotexistLabel = uilabel(app.UIFigure);
            app.UsernamedoesnotexistLabel.Visible = 'off';
            app.UsernamedoesnotexistLabel.Text = 'Username does not exist';
            app.IncorrectpasswordLabel = uilabel(app.UIFigure);
            app.IncorrectpasswordLabel.Visible = 'off';
            app.IncorrectpasswordLabel.Text = 'Incorrect password';
            app.LoginsuccessfullyLabel = uilabel(app.UIFigure);
            app.LoginsuccessfullyLabel.Visible = 'off';
            app.LoginsuccessfullyLabel.Text = 'Log in successfully';
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'Green tick.jpg';
            app.LogoutButton = uibutton(app.UIFigure, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Visible = 'off';
            app.LogoutButton.Text = 'Log out';
            app.SignupforanaccountLabel = uilabel(app.UIFigure);
            app.SignupforanaccountLabel.Visible = 'off';
            app.SignupforanaccountLabel.Text = 'Sign up for an account';
            app.UsernameEditField_2Label = uilabel(app.UIFigure);
            app.UsernameEditField_2Label.Visible = 'off';
            app.UsernameEditField_2Label.Text = 'Username';
            app.UsernameEditField_2 = uieditfield(app.UIFigure, 'text');
            app.UsernameEditField_2.Visible = 'off';
            app.PasswordEditField_2Label = uilabel(app.UIFigure);
            app.PasswordEditField_2Label.Visible = 'off';
            app.PasswordEditField_2Label.Text = 'Password';
            app.PasswordEditField_2 = uieditfield(app.UIFigure, 'text');
            app.PasswordEditField_2.Visible = 'off';
            app.ConfirmpasswordEditFieldLabel = uilabel(app.UIFigure);
            app.ConfirmpasswordEditFieldLabel.Visible = 'off';
            app.ConfirmpasswordEditFieldLabel.Text = 'Confirm password';
            app.ConfirmpasswordEditField = uieditfield(app.UIFigure, 'text');
            app.ConfirmpasswordEditField.Visible = 'off';
            app.CreateaccountButton = uibutton(app.UIFigure, 'push');
            app.CreateaccountButton.ButtonPushedFcn = createCallbackFcn(app, @CreateaccountButtonPushed, true);
            app.CreateaccountButton.Visible = 'off';
            app.CreateaccountButton.Text = 'Create account';
            app.HaveanaccountLabel = uilabel(app.UIFigure);
            app.HaveanaccountLabel.Visible = 'off';
            app.HaveanaccountLabel.Text = 'Have an account?';
            app.SigninButton_2 = uibutton(app.UIFigure, 'push');
            app.SigninButton_2.ButtonPushedFcn = createCallbackFcn(app, @SigninButton_2Pushed, true);
            app.SigninButton_2.Visible = 'off';
            app.SigninButton_2.Text = 'Sign in';
            app.CreateanaccountButton = uibutton(app.UIFigure, 'push');
            app.CreateanaccountButton.ButtonPushedFcn = createCallbackFcn(app, @CreateanaccountButtonPushed, true);
            app.CreateanaccountButton.Text = 'Create an account';
            app.ResetyourpasswordButton = uibutton(app.UIFigure, 'push');
            app.ResetyourpasswordButton.ButtonPushedFcn = createCallbackFcn(app, @ResetyourpasswordButtonPushed, true);
            app.ResetyourpasswordButton.Text = 'Reset your password';
            app.ThisusernameisusedbysomeoneelseLabel = uilabel(app.UIFigure);
            app.ThisusernameisusedbysomeoneelseLabel.Visible = 'off';
            app.ThisusernameisusedbysomeoneelseLabel.Text = 'This username is used by someone else';
            app.PasswordsdonotmatchLabel = uilabel(app.UIFigure);
            app.PasswordsdonotmatchLabel.Visible = 'off';
            app.PasswordsdonotmatchLabel.Text = 'Passwords do not match';
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.Visible = 'off';
            app.Image_2.ImageSource = 'Green tick.jpg';
            app.AccountcreatedsuccessfullyLabel = uilabel(app.UIFigure);
            app.AccountcreatedsuccessfullyLabel.Visible = 'off';
            app.AccountcreatedsuccessfullyLabel.Text = 'Account created successfully';
            app.EmailaddressEditFieldLabel = uilabel(app.UIFigure);
            app.EmailaddressEditFieldLabel.Visible = 'off';
            app.EmailaddressEditFieldLabel.Text = 'Email address';
            app.EmailaddressEditField = uieditfield(app.UIFigure, 'text');
            app.EmailaddressEditField.Visible = 'off';
            app.SendOTPButton = uibutton(app.UIFigure, 'push');
            app.SendOTPButton.ButtonPushedFcn = createCallbackFcn(app, @SendOTPButtonPushed, true);
            app.SendOTPButton.Visible = 'off';
            app.SendOTPButton.Text = 'Send OTP';
            app.OTPsentLabel = uilabel(app.UIFigure);
            app.OTPsentLabel.Visible = 'off';
            app.OTPsentLabel.Text = 'OTP sent';
            app.ResetpasswordLabel = uilabel(app.UIFigure);
            app.ResetpasswordLabel.Visible = 'off';
            app.ResetpasswordLabel.Text = 'Reset password';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sign_in_Create_account_Reset_password

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end