classdef Sign_in_page < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        LogoutButton               matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        LoginsuccessfullyLabel     matlab.ui.control.Label
        IncorrectpasswordLabel     matlab.ui.control.Label
        UsernamedoesnotexistLabel  matlab.ui.control.Label
        CreateanaccountButton      matlab.ui.control.StateButton
        DonthaveaaccountLabel      matlab.ui.control.Label
        ResetyourpasswordButton    matlab.ui.control.StateButton
        ForgetyourpasswordLabel    matlab.ui.control.Label
        SigninButton               matlab.ui.control.Button
        SigninLabel                matlab.ui.control.Label
        PasswordEditField          matlab.ui.control.EditField
        PasswordEditFieldLabel     matlab.ui.control.Label
        UsernameEditField          matlab.ui.control.EditField
        UsernameEditFieldLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        un 
        pw
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SigninButton
        function SigninButtonPushed(app, event)
            app.UsernamedoesnotexistLabel.Visible = 'off';
            app.IncorrectpasswordLabel.Visible = 'off';
            
            app.un=app.UsernameEditField.Value;
            app.pw=app.PasswordEditField.Value;
            PW=convertCharsToStrings(app.pw);
            UN=convertCharsToStrings(app.un);
            file=fopen("C:/Users/ACER_USER/Desktop/Matlab/Summer Research Intern 2021/GUI/Database.txt","r");
            database=fscanf(file,"%s");
            s1=split(database,"@");  %split 1
            s2=split(s1(2:length(s1)),"#");  %split 2
            
            siz=size(s2);
            for i=1:siz(1)
                for j=1:2
                    tem=cell2mat(s2(i,j));   %convert to normal vector
                    mat(i,j)=convertCharsToStrings(tem);
                end
            end
            
            uns=mat(:,1);
            pws=mat(:,2);
            i=1;
            I=0;
            while i<=length(uns)
                if uns(i)==UN
                    I=i;
                end
                i=i+1;
            end
            
            if I==0
                app.UsernamedoesnotexistLabel.Visible = 'on';
            else
                if pws(I)==PW
                    app.SigninLabel.Visible = 'off';
                    app.SigninButton.Visible = 'off';
                    app.PasswordEditField.Visible = 'off';
                    app.PasswordEditFieldLabel.Visible = 'off';
                    app.UsernameEditField.Visible = 'off';
                    app.UsernameEditFieldLabel.Visible = 'off';
                    app.ForgetyourpasswordLabel.Visible = 'off';
                    app.DonthaveaaccountLabel.Visible = 'off';
                    app.CreateanaccountButton.Visible = 'off';
                    app.ResetyourpasswordButton.Visible = 'off';
                    
                    app.Image.Visible = 'on';
                    app.LoginsuccessfullyLabel.Visible = 'on';
                    app.LogoutButton.Visible = 'on';
                    
                else
                    app.IncorrectpasswordLabel.Visible = 'on';
                end
            end
            
            fclose(file);


        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
            app.SigninLabel.Visible = 'on';
            app.SigninButton.Visible = 'on';
            app.PasswordEditField.Visible = 'on';
            app.PasswordEditFieldLabel.Visible = 'on';
            app.UsernameEditField.Visible = 'on';
            app.UsernameEditFieldLabel.Visible = 'on';
            app.ForgetyourpasswordLabel.Visible = 'on';
            app.DonthaveaaccountLabel.Visible = 'on';
            app.CreateanaccountButton.Visible = 'on';
            app.ResetyourpasswordButton.Visible = 'on';
            
            app.Image.Visible = 'off';
            app.LoginsuccessfullyLabel.Visible = 'off';
            app.LogoutButton.Visible = 'off';
            
            app.UsernameEditField.Value = '';
            app.PasswordEditField.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UsernameEditFieldLabel = uilabel(app.UIFigure);
            app.UsernameEditFieldLabel.Text = 'Username';
            app.UsernameEditField = uieditfield(app.UIFigure, 'text');
            app.PasswordEditFieldLabel = uilabel(app.UIFigure);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.UIFigure, 'text');
            app.SigninLabel = uilabel(app.UIFigure);
            app.SigninLabel.Text = 'Sign in';
            app.SigninButton = uibutton(app.UIFigure, 'push');
            app.SigninButton.ButtonPushedFcn = createCallbackFcn(app, @SigninButtonPushed, true);
            app.SigninButton.Text = {'Sign in'; ''};
            app.ForgetyourpasswordLabel = uilabel(app.UIFigure);
            app.ForgetyourpasswordLabel.Text = 'Forget your password?';
            app.ResetyourpasswordButton = uibutton(app.UIFigure, 'state');
            app.ResetyourpasswordButton.Text = 'Reset your password';
            app.DonthaveaaccountLabel = uilabel(app.UIFigure);
            app.DonthaveaaccountLabel.Text = 'Don''t have a account?';
            app.CreateanaccountButton = uibutton(app.UIFigure, 'state');
            app.CreateanaccountButton.Text = 'Create an account';
            app.UsernamedoesnotexistLabel = uilabel(app.UIFigure);
            app.UsernamedoesnotexistLabel.Visible = 'off';
            app.UsernamedoesnotexistLabel.Text = 'Username does not exist';
            app.IncorrectpasswordLabel = uilabel(app.UIFigure);
            app.IncorrectpasswordLabel.Visible = 'off';
            app.IncorrectpasswordLabel.Text = 'Incorrect password';
            app.LoginsuccessfullyLabel = uilabel(app.UIFigure);
            app.LoginsuccessfullyLabel.Visible = 'off';
            app.LoginsuccessfullyLabel.Text = 'Log in successfully';
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'Green tick.jpg';
            app.LogoutButton = uibutton(app.UIFigure, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Visible = 'off';
            app.LogoutButton.Text = 'Log out';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sign_in_page

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end